classdef Jacobi3x3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        NumeroMaxIteraciones          matlab.ui.control.NumericEditField
        NmeromximodeiteracionesLabel  matlab.ui.control.Label
        CalcularButton                matlab.ui.control.Button
        UITable                       matlab.ui.control.Table
        ResultadodelosxiLabel         matlab.ui.control.Label
        x3Label_5                     matlab.ui.control.Label
        Label_9                       matlab.ui.control.Label
        x2Label_5                     matlab.ui.control.Label
        valorInicialx3_2              matlab.ui.control.NumericEditField
        x1Label_9                     matlab.ui.control.Label
        Label_8                       matlab.ui.control.Label
        valorInicialx2_2              matlab.ui.control.NumericEditField
        Label_7                       matlab.ui.control.Label
        valorInicialx1_2              matlab.ui.control.NumericEditField
        CriterioParada                matlab.ui.control.NumericEditField
        CriteriodeparadaenEaEditFieldLabel  matlab.ui.control.Label
        ValorinicialdelosxiLabel      matlab.ui.control.Label
        valorInicialx1                matlab.ui.control.NumericEditField
        valorInicialx2                matlab.ui.control.NumericEditField
        valorInicialx3                matlab.ui.control.NumericEditField
        Label_6                       matlab.ui.control.Label
        Label_5                       matlab.ui.control.Label
        Label_4                       matlab.ui.control.Label
        x1Label_8                     matlab.ui.control.Label
        x2Label_4                     matlab.ui.control.Label
        x3Label_4                     matlab.ui.control.Label
        SistemadeecuacionespreviamenteorganizadoLabel  matlab.ui.control.Label
        text5                         matlab.ui.control.Label
        a34                           matlab.ui.control.NumericEditField
        a24                           matlab.ui.control.NumericEditField
        a14                           matlab.ui.control.NumericEditField
        Label_3                       matlab.ui.control.Label
        Label_2                       matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        x3Label_3                     matlab.ui.control.Label
        a33                           matlab.ui.control.NumericEditField
        x2Label_3                     matlab.ui.control.Label
        a32                           matlab.ui.control.NumericEditField
        x1Label_7                     matlab.ui.control.Label
        a31                           matlab.ui.control.NumericEditField
        a21                           matlab.ui.control.NumericEditField
        x1Label_6                     matlab.ui.control.Label
        a22                           matlab.ui.control.NumericEditField
        x2Label_2                     matlab.ui.control.Label
        a23                           matlab.ui.control.NumericEditField
        x3Label_2                     matlab.ui.control.Label
        x3Label                       matlab.ui.control.Label
        a13                           matlab.ui.control.NumericEditField
        x2Label                       matlab.ui.control.Label
        a12                           matlab.ui.control.NumericEditField
        x1Label                       matlab.ui.control.Label
        a11                           matlab.ui.control.NumericEditField
    end

    
    methods (Access = private)
        
        %-------------------------------------------------------
        % Runs the Jacobi method for A*x = b.
        %
        % ON ENTRY :
        %   A        n-by-n matrix
        %   b        n-dimensional vector
        %   epsilon  accuracy requirement
        %   maxit    maximal number of iterations
        %   x        start vector for the iteration
        %
        % ON RETURN :
        %   x        approximate solution to A*x = b.
        %-------------------------------------------------------
        function x = Jacobi(app, A, b, epsilon, maxit, x)
        
        % check if the entered matrix is a square matrix
        [na, ma] = size(A);
        if na ~= ma
            disp('ERROR: Matrix A must be a square matrix')
            return
        end
        
        % check if b is a column matrix
        [nb, mb] = size (b);
        if nb ~= na || mb~=1
           disp( 'ERROR: Matrix b must be a column matrix')
           return
        end
        
        dx = zeros(na,1);
        for k=1:maxit
            sum = 0;
            for i=1:na
                dx(i) = b(i);
                for j=1:na
                    dx(i) = dx(i) - A(i,j)*x(j); 
                end
                dx(i) = dx(i)/A(i,i);
                x(i) = x(i) + dx(i);
                if (dx(i) >= 0)
                    sum = sum + dx(i);
                else 
                    sum = sum - dx(i);
                end
            end
            if(sum <= epsilon)
                break
            end
        end
        fprintf('The final answer obtained after %g iterations is  \n', k);
        
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            A = [app.a11.Value app.a12.Value app.a13.Value; app.a21.Value app.a22.Value app.a23.Value; app.a31.Value app.a32.Value app.a33.Value];
            b = [app.a14.Value app.a24.Value app.a34.Value]';
            epsilon = app.CriterioParada.Value;
            maxit = (app.NumeroMaxIteraciones.Value);
            x = [app.valorInicialx1.Value app.valorInicialx2.Value app.valorInicialx3.Value];
            x = Jacobi(app, A,b,epsilon,maxit,x);

fprintf('MATLAB Built-in Function\n');
x = linsolve(A,b)

fprintf('MATLAB Built-in Function\n');
x = A\b

fprintf('MATLAB Built-in Function\n');
x = pinv(A)*b

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.a11 = uieditfield(app.UIFigure, 'numeric');
            app.x1Label = uilabel(app.UIFigure);
            app.x1Label.Text = 'x1';
            app.a12 = uieditfield(app.UIFigure, 'numeric');
            app.x2Label = uilabel(app.UIFigure);
            app.x2Label.Text = 'x2';
            app.a13 = uieditfield(app.UIFigure, 'numeric');
            app.x3Label = uilabel(app.UIFigure);
            app.x3Label.Text = 'x3';
            app.x3Label_2 = uilabel(app.UIFigure);
            app.x3Label_2.Text = 'x3';
            app.a23 = uieditfield(app.UIFigure, 'numeric');
            app.x2Label_2 = uilabel(app.UIFigure);
            app.x2Label_2.Text = 'x2';
            app.a22 = uieditfield(app.UIFigure, 'numeric');
            app.x1Label_6 = uilabel(app.UIFigure);
            app.x1Label_6.Text = 'x1';
            app.a21 = uieditfield(app.UIFigure, 'numeric');
            app.a31 = uieditfield(app.UIFigure, 'numeric');
            app.x1Label_7 = uilabel(app.UIFigure);
            app.x1Label_7.Text = 'x1';
            app.a32 = uieditfield(app.UIFigure, 'numeric');
            app.x2Label_3 = uilabel(app.UIFigure);
            app.x2Label_3.Text = 'x2';
            app.a33 = uieditfield(app.UIFigure, 'numeric');
            app.x3Label_3 = uilabel(app.UIFigure);
            app.x3Label_3.Text = 'x3';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '=';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '=';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '=';
            app.a14 = uieditfield(app.UIFigure, 'numeric');
            app.a24 = uieditfield(app.UIFigure, 'numeric');
            app.a34 = uieditfield(app.UIFigure, 'numeric');
            app.text5 = uilabel(app.UIFigure);
            app.text5.Tag = 'text5';
            app.text5.Text = 'SOLUCIÓN DE SISTEMAS DE ECUACIONES 3X3 POR MEDIO DE JACOBI';
            app.SistemadeecuacionespreviamenteorganizadoLabel = uilabel(app.UIFigure);
            app.SistemadeecuacionespreviamenteorganizadoLabel.Text = 'Sistema de ecuaciones previamente organizado';
            app.x3Label_4 = uilabel(app.UIFigure);
            app.x3Label_4.Text = 'x3';
            app.x2Label_4 = uilabel(app.UIFigure);
            app.x2Label_4.Text = 'x2';
            app.x1Label_8 = uilabel(app.UIFigure);
            app.x1Label_8.Text = 'x1';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '=';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '=';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '=';
            app.valorInicialx3 = uieditfield(app.UIFigure, 'numeric');
            app.valorInicialx2 = uieditfield(app.UIFigure, 'numeric');
            app.valorInicialx1 = uieditfield(app.UIFigure, 'numeric');
            app.ValorinicialdelosxiLabel = uilabel(app.UIFigure);
            app.ValorinicialdelosxiLabel.Text = 'Valor inicial de los xi';
            app.CriteriodeparadaenEaEditFieldLabel = uilabel(app.UIFigure);
            app.CriteriodeparadaenEaEditFieldLabel.Text = 'Criterio de parada en %Ea';
            app.CriterioParada = uieditfield(app.UIFigure, 'numeric');
            app.valorInicialx1_2 = uieditfield(app.UIFigure, 'numeric');
            app.valorInicialx1_2.Editable = 'off';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '=';
            app.valorInicialx2_2 = uieditfield(app.UIFigure, 'numeric');
            app.valorInicialx2_2.Editable = 'off';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '=';
            app.x1Label_9 = uilabel(app.UIFigure);
            app.x1Label_9.Text = 'x1';
            app.valorInicialx3_2 = uieditfield(app.UIFigure, 'numeric');
            app.valorInicialx3_2.Editable = 'off';
            app.x2Label_5 = uilabel(app.UIFigure);
            app.x2Label_5.Text = 'x2';
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '=';
            app.x3Label_5 = uilabel(app.UIFigure);
            app.x3Label_5.Text = 'x3';
            app.ResultadodelosxiLabel = uilabel(app.UIFigure);
            app.ResultadodelosxiLabel.Text = 'Resultado de los xi';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Iteración'; 'x1'; 'x2'; 'x3'; 'Error x1'; 'Error x2'; 'Error x3'};
            app.UITable.RowName = {};
            app.CalcularButton = uibutton(app.UIFigure, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.NmeromximodeiteracionesLabel = uilabel(app.UIFigure);
            app.NmeromximodeiteracionesLabel.Text = 'Número máximo de iteraciones';
            app.NumeroMaxIteraciones = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Jacobi3x3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end