classdef MetodoFalsaPosicion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1      matlab.ui.Figure
        uipanel1     matlab.ui.container.Panel
        btnLimpiar   matlab.ui.control.Button
        btnCalcular  matlab.ui.control.Button
        Tolerancia   matlab.ui.control.EditField
        text4        matlab.ui.control.Label
        limSuperior  matlab.ui.control.EditField
        text3        matlab.ui.control.Label
        limInferior  matlab.ui.control.EditField
        text2        matlab.ui.control.Label
        funcion      matlab.ui.control.EditField
        text1        matlab.ui.control.Label
        respuesta    matlab.ui.control.EditField
        text6        matlab.ui.control.Label
        tabla        matlab.ui.control.Table
        text5        matlab.ui.control.Label
    end

    
    methods (Access = private)
        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)
            
            % Set component properties that require runtime configuration
            app.tabla.ColumnFormat = {[] [] [] [] [] [] []};
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function frmFalsaPosicion_OpeningFcn(app, varargin)
            % --- Executes just before frmFalsaPosicion is made visible.
            
            % Add runtime required configuration - Added by Migration Tool
            addRuntimeConfigurations(app);
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to frmFalsaPosicion (see VARARGIN)
            
            % Choose default command line output for frmFalsaPosicion
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: btnCalcular
        function btnCalcular_Callback(app, event)
            % --- Executes on button press in btnCalcular.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            f=get(handles.funcion,'string');
            f=inline(f);
            xai=str2double(get(handles.limInferior,'string')); % valor de xi
            xas=str2double(get(handles.limSuperior,'string')); % valor de xs
            tol=str2double(get(handles.tolerancia,'string')); % tolerancia
            i=1;
            ea(1)=100;
            %%%%% Metodo Bisección %%%%%%
            if f(xai)*f(xas)<0; % Comprobando que la raiz se encuentra en este intervalo
            xi(1)=xai;
            xs(1)=xas;
            xr(1)= xs(1)-f(xs(1))*(xi(1) - xs(1))/(f(xi(1))-f(xs(1)));
            %Limpiar tabla antes de mostrar resultado
            set(handles.tabla,'Data',{})
            % Limpiar tabla, grafico en caso de que antes se haya graficado una funcion
            set(handles.tabla,'Data',{})
            set(handles.respuesta,'string','No hay raiz');
            while abs(ea(i))>=tol
            if f(xi(i))*f(xr(i))<0 % Condicion de cumplimiento
            xi(i+1)=xi(i);
            xs(i+1)=xr(i); % Es la raiz(xr) si se cumple condicion
            end
            if f(xi(i))*f(xr(i))>0 % Condicion de cumplimiento
            xi(i+1)=xr(i); % Es la raiz(xr) si se cumple condicion
            xs(i+1)=xs(i);
            end
            xr(i+1)= xs(i+1)-f(xs(i+1))*(xi(i+1) - xs(i+1))/(f(xi(i+1))-f(xs(i+1)));
            ea(i+1)=abs((xr(i+1)-xr(i))/(xr(i+1))*100);% error absoluto
            % Mostrara datos en tabla
               %valores = {i xa(i+1) xb(i+1) xf(i+1) ea(i+1)};
               %temp=get(handles.tabla,'data');
               %valoresNuevos=[valores;temp];
               %set(handles.tabla,'Data',valoresNuevos)
            
            newRow ={i xi(i+1) xr(i+1) xs(i+1) f(xi(i+1)) f(xr(i+1)) f(xs(i+1)) ea(i+1)};
                oldData = get(handles.tabla,'Data');
                newData=[oldData; newRow];
                set(handles.tabla,'Data',newData)


            i=i+1;
            end % Cerramos while
            % Mostrando respuesta en textbox con formato coma flotante a 6 cifras decimales
            respuesta=sprintf('%0.6f',xr(i));
            set(handles.respuesta,'string',respuesta);
            
            else
            set(handles.respuesta,'string','No existe la raiz en el intervalo');
            zoom on
            end
        end

        % Button pushed function: btnLimpiar
        function btnLimpiar_Callback(app, event)
            % --- Executes on button press in btnLimpiar.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            %limpiar tabla
            set(handles.tabla,'Data',{})
            %limpiar textboxs
            set(handles.funcion,'string','');
            set(handles.limInferior,'string','');
            set(handles.limSuperior,'string','');
            set(handles.tolerancia,'string','');
            set(handles.respuesta,'string','');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'frmFalsaPosicion';
            app.figure1.Tag = 'figure1';
            app.text5 = uilabel(app.figure1);
            app.text5.Tag = 'text5';
            app.text5.Text = 'METODO FALSA POSICION';
            app.tabla = uitable(app.figure1);
            app.tabla.ColumnName = {'Iteracion'; 'xs'; 'xr'; 'xi'; 'f(xi)'; 'f(xr)'; 'f(xs)'; 'Ea(%)'};
            app.tabla.ColumnEditable = [false false false false false false false];
            app.tabla.Tag = 'tabla';
            app.text6 = uilabel(app.figure1);
            app.text6.Tag = 'text6';
            app.text6.Text = 'La Raiz es:';
            app.respuesta = uieditfield(app.figure1, 'text');
            app.respuesta.Tag = 'respuesta';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Tag = 'uipanel1';
            app.text1 = uilabel(app.uipanel1);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Funcion Matematica:';
            app.funcion = uieditfield(app.uipanel1, 'text');
            app.funcion.Tag = 'funcion';
            app.text2 = uilabel(app.uipanel1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Limite superior:';
            app.limInferior = uieditfield(app.uipanel1, 'text');
            app.limInferior.Tag = 'limInferior';
            app.text3 = uilabel(app.uipanel1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Limite inferior:';
            app.limSuperior = uieditfield(app.uipanel1, 'text');
            app.limSuperior.Tag = 'limSuperior';
            app.text4 = uilabel(app.uipanel1);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Tolerancia:';
            app.Tolerancia = uieditfield(app.uipanel1, 'text');
            app.Tolerancia.Tag = 'tolerancia';
            app.btnCalcular = uibutton(app.uipanel1, 'push');
            app.btnCalcular.ButtonPushedFcn = createCallbackFcn(app, @btnCalcular_Callback, true);
            app.btnCalcular.Tag = 'btnCalcular';
            app.btnCalcular.Text = 'Calcular';
            app.btnLimpiar = uibutton(app.uipanel1, 'push');
            app.btnLimpiar.ButtonPushedFcn = createCallbackFcn(app, @btnLimpiar_Callback, true);
            app.btnLimpiar.Tag = 'btnLimpiar';
            app.btnLimpiar.Text = 'Limpiar';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MetodoFalsaPosicion(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)frmFalsaPosicion_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end