classdef MetodoNewtonRaphson < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        limpiar                   matlab.ui.control.Button
        TABLA                     matlab.ui.control.Table
        RAIZ                      matlab.ui.control.NumericEditField
        RazsolucinEditFieldLabel  matlab.ui.control.Label
        CALCULAR                  matlab.ui.control.Button
        TOL                       matlab.ui.control.NumericEditField
        IngresetoleranciaEditFieldLabel  matlab.ui.control.Label
        FXDX                      matlab.ui.control.EditField
        FxdxEditFieldLabel        matlab.ui.control.Label
        FX                        matlab.ui.control.EditField
        IngreseFxEditFieldLabel   matlab.ui.control.Label
        XI                        matlab.ui.control.NumericEditField
        IngresevalorinicialEditFieldLabel  matlab.ui.control.Label
        TITULO                    matlab.ui.control.Label
        uipanel1                  matlab.ui.container.Panel
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CALCULAR
        function CALCULARPushed(app, event)
            format longG
            xi = app.XI.Value;
            tol = app.TOL.Value;
            funcion  = app.FX.Value;
            f = str2func(strcat('@(x) ',funcion));
            fx =str2sym(string(funcion));
            dfx = diff(fx);
            
            app.FXDX.Value=char(dfx);
            error=100;
            i=1;
            MATRIZ=zeros(1,6);
            while error>tol
                if i == 50
                    break
                end
                pa = f(xi);
                pd = subs(dfx,xi);
                pc = xi-(pa/pd);
                error = abs((pc-xi)/pc)*100;
                MATRIZ(i,:)=[i' xi' pa' pd' pc' error'];
                xi = pc;
                i = i+1;
            end
            MATRIZ=round(MATRIZ*10^6)/10^6;
            app.TABLA.Data=MATRIZ;
            app.RAIZ.Value=double(pc);            
        end

        % Callback function
        function YoutubeButtonPushed(app, event)
           
        end

        % Button pushed function: limpiar
        function limpiarButtonPushed(app, event)
            set(app.TABLA,'Data',{})
            %limpiar textboxs
            app.FX.Value = "";
            app.FXDX.Value="";
            app.RAIZ.Value=0;
            app.TOL.Value=0;
            app.XI.Value=0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.uipanel1 = uipanel(app.UIFigure);
            app.uipanel1.Tag = 'uipanel1';
            app.TITULO = uilabel(app.UIFigure);
            app.TITULO.Text = 'Método de Newton Rhapson';
            app.IngresevalorinicialEditFieldLabel = uilabel(app.UIFigure);
            app.IngresevalorinicialEditFieldLabel.Text = 'Ingrese valor inicial';
            app.XI = uieditfield(app.UIFigure, 'numeric');
            app.IngreseFxEditFieldLabel = uilabel(app.UIFigure);
            app.IngreseFxEditFieldLabel.Text = 'Ingrese F(x)';
            app.FX = uieditfield(app.UIFigure, 'text');
            app.FxdxEditFieldLabel = uilabel(app.UIFigure);
            app.FxdxEditFieldLabel.Text = 'F(x) dx';
            app.FXDX = uieditfield(app.UIFigure, 'text');
            app.FXDX.Editable = 'off';
            app.IngresetoleranciaEditFieldLabel = uilabel(app.UIFigure);
            app.IngresetoleranciaEditFieldLabel.Text = 'Ingrese tolerancia';
            app.TOL = uieditfield(app.UIFigure, 'numeric');
            app.CALCULAR = uibutton(app.UIFigure, 'push');
            app.CALCULAR.ButtonPushedFcn = createCallbackFcn(app, @CALCULARPushed, true);
            app.CALCULAR.Text = 'Calcular';
            app.RazsolucinEditFieldLabel = uilabel(app.UIFigure);
            app.RazsolucinEditFieldLabel.Text = 'Raíz solución';
            app.RAIZ = uieditfield(app.UIFigure, 'numeric');
            app.RAIZ.ValueDisplayFormat = '%11.6g';
            app.RAIZ.Editable = 'off';
            app.TABLA = uitable(app.UIFigure);
            app.TABLA.ColumnName = {'Iteracion'; 'Xi'; 'F(X)'; 'F(X)dx'; 'Xi+1'; 'ERROR'};
            app.TABLA.RowName = {};
            app.limpiar = uibutton(app.UIFigure, 'push');
            app.limpiar.ButtonPushedFcn = createCallbackFcn(app, @limpiarButtonPushed, true);
            app.limpiar.Tag = 'limpiar';
            app.limpiar.Text = 'Limpiar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MetodoNewtonRaphson

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end