classdef MetodoPuntoFijo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        raiz                    matlab.ui.control.NumericEditField
        LaRaizesEditFieldLabel  matlab.ui.control.Label
        uipanel1                matlab.ui.container.Panel
        btnLimpiar              matlab.ui.control.Button
        btnCalcular             matlab.ui.control.Button
        Tolerancia              matlab.ui.control.EditField
        text4                   matlab.ui.control.Label
        valorInicial            matlab.ui.control.EditField
        text3                   matlab.ui.control.Label
        funcion                 matlab.ui.control.EditField
        text1                   matlab.ui.control.Label
        tabla                   matlab.ui.control.Table
        text5                   matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        function addRuntimeConfigurations(app)
            
            % Setea las propiedades de los componentes que requieren una
            % configuración en tiempo de ejecución
            app.tabla.ColumnFormat = {[] [] [] []};
        endfunction results = func(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: btnCalcular
        function btnCalcularButtonPushed(app, event)

            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % Obtine la función ingresada
            f=app.funcion.Value;
            fx = (f);
            % Obtiene el valor inicial ingresado
            x0=app.valorInicial.Value;
            % Obtiene la tolerancia ingresada
            tolerancia = app.Tolerancia.Value;
            i=1;
            fprintf('\ti  \txi  \terror \n');
            A = zeros(1,4)
            % Lugar iteraciones
            A(1,1) = 1
            % Lugar de x
            A(1,2) = x0-48
            % Lugar de f(x)
            A(1,3) = subs(str2sym(fx), x0)
            % Lugar del error 
            A(1,4) = ((A(1,3)-A(1,2))/A(1,3))*100
            error = tolerancia + 1
            xi = 0
            while (abs(error)>tolerancia)
                % Aumento de iteraciones
                i = i+1;
                % Calcular xi
                xi= subs(str2sym(fx),x0);
                % Calcular f(x)
                fxi= subs(str2sym(fx), xi);
                % Calcular el error
                error=((fxi-xi)/fxi)*100;
                % Asigna las posiciones debidas
                A(i,1) = i
                A(i,2) = xi
                A(i,3) = fxi
                A(i,4) = error
                % Obtiene la tabla
                C = num2cell(A)
                % Setea la tabla
                app.tabla.Data = C
                % Asigna el nuevo valor 
                x0=xi;
                
            end
            % Terminando se setea el último valor de xi
            app.raiz.Value = double(xi)
        end

        % Button pushed function: btnLimpiar
        function btnLimpiarButtonPushed(app, event)
            set(app.tabla,'Data',{})
            %limpiar textboxs
            app.funcion.Value = "";
            app.valorInicial.Value="0";
            app.raiz.Value=0;
            app.Tolerancia.Value="0";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.text5 = uilabel(app.UIFigure);
            app.text5.Tag = 'text5';
            app.text5.Text = 'METODO PUNTO FIJO';
            app.tabla = uitable(app.UIFigure);
            app.tabla.ColumnName = {'Iteracion'; 'xi'; 'g(xi)'; 'Ea(%)'};
            app.tabla.ColumnEditable = [false false false false false false false];
            app.tabla.Tag = 'tabla';
            app.uipanel1 = uipanel(app.UIFigure);
            app.uipanel1.Tag = 'uipanel1';
            app.text1 = uilabel(app.uipanel1);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Funcion Matematica Despejada:';
            app.funcion = uieditfield(app.uipanel1, 'text');
            app.funcion.Tag = 'funcion';
            app.text3 = uilabel(app.uipanel1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Valor Inicial:';
            app.valorInicial = uieditfield(app.uipanel1, 'text');
            app.valorInicial.Tag = 'limSuperior';
            app.text4 = uilabel(app.uipanel1);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Tolerancia:';
            app.Tolerancia = uieditfield(app.uipanel1, 'text');
            app.Tolerancia.Tag = 'tolerancia';
            app.btnCalcular = uibutton(app.uipanel1, 'push');
            app.btnCalcular.ButtonPushedFcn = createCallbackFcn(app, @btnCalcularButtonPushed, true);
            app.btnCalcular.Tag = 'btnCalcular';
            app.btnCalcular.Text = 'Calcular';
            app.btnLimpiar = uibutton(app.uipanel1, 'push');
            app.btnLimpiar.ButtonPushedFcn = createCallbackFcn(app, @btnLimpiarButtonPushed, true);
            app.btnLimpiar.Tag = 'btnLimpiar';
            app.btnLimpiar.Text = 'Limpiar';
            app.LaRaizesEditFieldLabel = uilabel(app.UIFigure);
            app.LaRaizesEditFieldLabel.Text = 'La Raiz es:';
            app.raiz = uieditfield(app.UIFigure, 'numeric');
            app.raiz.ValueDisplayFormat = '%.6f';
            app.raiz.Tag = 'respuesta';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MetodoPuntoFijo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end